classdef Prototipo1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        CargaTab              matlab.ui.container.Tab
        RecortarmatrizButton  matlab.ui.control.Button
        CargarImagenButton    matlab.ui.control.Button
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
        GrisesTab             matlab.ui.container.Tab
        ConvertirButton       matlab.ui.control.Button
        UIAxes_3              matlab.ui.control.UIAxes
        GaussTab              matlab.ui.container.Tab
        ConvertirButton_2     matlab.ui.control.Button
        UIAxes_4              matlab.ui.control.UIAxes
        ContrasteTab          matlab.ui.container.Tab
        ConvertirButton_3     matlab.ui.control.Button
        UIAxes_5              matlab.ui.control.UIAxes
        ResaltarBordesTab     matlab.ui.container.Tab
        ConvertirButton_4     matlab.ui.control.Button
        UIAxes_6              matlab.ui.control.UIAxes
        BinariaTab            matlab.ui.container.Tab
        ConvertirButton_5     matlab.ui.control.Button
        UIAxes_7              matlab.ui.control.UIAxes
        Tab                   matlab.ui.container.Tab
        ConvertirButton_6     matlab.ui.control.Button
        UIAxes_8              matlab.ui.control.UIAxes
        FinalTab              matlab.ui.container.Tab
        ConvertirButton_7     matlab.ui.control.Button
        UIAxes_9              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    ImagenTrabajo     % Matriz de la imagen a color original
    ImagenGris        % Matriz de la Fase 1
    ImagenSuave       % Matriz de la Fase 2
    ImagenContraste   % Matriz de la Fase 3
    ImagenBordes      % Matriz de la Fase 4
    ImagenMorfologica % Matriz de la Fase 5
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarImagenButton
        function CargarImagenButtonPushed(app, event)

        [filename, pathname] = uigetfile({'*.jpg;*.png;*.jpeg', 'Archivos de Imagen'});
        
        % Si el usuario cancela, salir
        if isequal(filename,0)
            return;
        end
        
        % Cargar la imagen en la variable de trabajo
        app.ImagenTrabajo = imread(fullfile(pathname, filename));
        
        % Mostrar en el primer panel de ejes
        imshow(app.ImagenTrabajo, 'Parent', app.UIAxes);
        title(app.UIAxes, 'Imagen Original');

        end

        % Button pushed function: RecortarmatrizButton
        function RecortarmatrizButtonPushed(app, event)
          
        % Verificar que la imagen exista
        if isempty(app.ImagenTrabajo)
            uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
            return;
        end
        
        % Permitir al usuario dibujar un rectángulo sobre la matrícula
        roi = drawrectangle(app.UIAxes, 'Label', 'Placa', 'Color', 'r');
        
        % Obtener las coordenadas del rectángulo [xmin ymin width height]
        rect = roi.Position;
        
        % Recortar la imagen usando la variable principal
        placaRecortada = imcrop(app.ImagenTrabajo, rect);
        
        % Mostrar el recorte en el segundo panel
        imshow(placaRecortada, 'Parent', app.UIAxes_2);
        title(app.UIAxes_2, 'Recorte de Placa');
        
        % Opcional: Eliminar el rectángulo de la imagen original después del recorte
        delete(roi);

        end

        % Button pushed function: ConvertirButton
        function ConvertirButtonPushed(app, event)
        % Validar que exista la imagen inicial
        if isempty(app.ImagenTrabajo)
            uialert(app.UIFigure, 'Primero cargue una imagen.', 'Error de Secuencia');
            return;
        end
        
        % Conversión a escala de grises
        app.ImagenGris = rgb2gray(app.ImagenTrabajo);
        
        % Mostrar en el primer tab de proceso (UIAxes_3)
        imshow(app.ImagenGris, 'Parent', app.UIAxes_3);
        title(app.UIAxes_3, 'Fase 1: Escala de Grises');
        end

        % Button pushed function: ConvertirButton_2
        function ConvertirButton_2Pushed(app, event)
                % Validar que la fase anterior se haya ejecutado
        if isempty(app.ImagenGris)
            uialert(app.UIFigure, 'Ejecute la Fase 1 primero.', 'Error de Secuencia');
            return;
        end
        
        % Aplicar filtro gaussiano para reducir ruido
        app.ImagenSuave = imgaussfilt(app.ImagenGris, 2);
        
        % Mostrar en UIAxes_4
        imshow(app.ImagenSuave, 'Parent', app.UIAxes_4);
        title(app.UIAxes_4, 'Fase 2: Suavizado Gaussiano');
        end

        % Button pushed function: ConvertirButton_3
        function ConvertirButton_3Pushed(app, event)

    if isempty(app.ImagenSuave)
        uialert(app.UIFigure, 'Ejecute la Fase 2 primero.', 'Error de Secuencia');
        return;
    end
    
    % Ecualización de histograma adaptativa
    app.ImagenContraste = adapthisteq(app.ImagenSuave);
    
    % Mostrar en UIAxes_5
    imshow(app.ImagenContraste, 'Parent', app.UIAxes_5);
    title(app.UIAxes_5, 'Fase 3: Aumento de Contraste');

            
        end

        % Button pushed function: ConvertirButton_4
        function ConvertirButton_4Pushed(app, event)

    if isempty(app.ImagenContraste)
        uialert(app.UIFigure, 'Ejecute la Fase 3 primero.', 'Error de Secuencia');
        return;
    end
    
    % Detección de bordes verticales usando el operador Sobel
    app.ImagenBordes = edge(app.ImagenContraste, 'sobel', 'vertical');
    
    % Mostrar en UIAxes_6
    imshow(app.ImagenBordes, 'Parent', app.UIAxes_6);
    title(app.UIAxes_6, 'Fase 4: Bordes Verticales');
        end

        % Button pushed function: ConvertirButton_5
        function ConvertirButton_5Pushed(app, event)

    if isempty(app.ImagenBordes)
        uialert(app.UIFigure, 'Ejecute la Fase 4 primero.', 'Error de Secuencia');
        return;
    end
    
    % Crear el elemento estructurante (más ancho que alto)
    ee = strel('rectangle', [4 15]); 
    
    % Aplicar dilatación y luego cierre para conectar los bordes
    I_dilatada = imdilate(app.ImagenBordes, ee);
    app.ImagenMorfologica = imclose(I_dilatada, ee);
    
    % Mostrar en UIAxes_7
    imshow(app.ImagenMorfologica, 'Parent', app.UIAxes_7);
    title(app.UIAxes_7, 'Fase 5: Operación Morfológica');
        end

        % Button pushed function: ConvertirButton_6
        function ConvertirButton_6Pushed(app, event)

    % 1. Validar que la Fase 5 se haya ejecutado
    if isempty(app.ImagenMorfologica)
        uialert(app.UIFigure, 'Ejecute la Fase 5 primero.', 'Error de Secuencia');
        return;
    end
    
    % 2. Opcional pero recomendado: Limpiar manchas blancas muy pequeñas (ruido restante)
    % Elimina regiones conectadas que tengan menos de 500 píxeles de área
    imagenLimpia = bwareaopen(app.ImagenMorfologica, 500);
    
    % 3. Extraer las propiedades de las regiones resultantes
    stats = regionprops(imagenLimpia, 'BoundingBox', 'Area');
    
    % Validar si se encontró alguna región
    if isempty(stats)
        uialert(app.UIFigure, 'No se detectó ninguna posible placa en la imagen.', 'Aviso');
        return;
    end
    
    % 4. Seleccionar el mejor candidato
    % En una imagen de un auto, la placa suele ser el bloque de bordes más denso.
    % Para este prototipo, seleccionaremos la región con el Área máxima.
    [~, indiceMax] = max([stats.Area]);
    cajaPlaca = stats(indiceMax).BoundingBox;
    
    % 5. Recortar la imagen ORIGINAL a color usando las coordenadas encontradas
    placaRecortada = imcrop(app.ImagenTrabajo, cajaPlaca);
    
    % 6. Mostrar el resultado en el panel de recorte (UIAxes_2)
    imshow(placaRecortada, 'Parent', app.UIAxes_8);
    title(app.UIAxes_2, 'Placa Detectada Automáticamente');
    
    % 7. Opcional: Dibujar un rectángulo verde sobre la imagen original (UIAxes)
    % Esto te ayuda a visualizar exactamente dónde hizo la detección el algoritmo
    imshow(app.ImagenTrabajo, 'Parent', app.UIAxes); % Refresca la original
    title(app.UIAxes, 'Imagen Original con Detección');
    hold(app.UIAxes, 'on');
    rectangle(app.UIAxes, 'Position', cajaPlaca, 'EdgeColor', 'g', 'LineWidth', 3);
    hold(app.UIAxes, 'off');

        end

        % Button pushed function: ConvertirButton_7
        function ConvertirButton_7Pushed(app, event)

    % 1. Validar que la Fase 5 se haya ejecutado
    if isempty(app.ImagenMorfologica)
        uialert(app.UIFigure, 'Ejecute la Fase 5 primero.', 'Error de Secuencia');
        return;
    end
    
    % 2. Opcional pero recomendado: Limpiar manchas blancas muy pequeñas (ruido restante)
    % Elimina regiones conectadas que tengan menos de 500 píxeles de área
    imagenLimpia = bwareaopen(app.ImagenMorfologica, 500);
    
    % 3. Extraer las propiedades de las regiones resultantes
    stats = regionprops(imagenLimpia, 'BoundingBox', 'Area');
    
    % Validar si se encontró alguna región
    if isempty(stats)
        uialert(app.UIFigure, 'No se detectó ninguna posible placa en la imagen.', 'Aviso');
        return;
    end
    
    % 4. Seleccionar el mejor candidato
    % En una imagen de un auto, la placa suele ser el bloque de bordes más denso.
    % Para este prototipo, seleccionaremos la región con el Área máxima.
    [~, indiceMax] = max([stats.Area]);
    cajaPlaca = stats(indiceMax).BoundingBox;
    
    % 5. Recortar la imagen ORIGINAL a color usando las coordenadas encontradas
    placaRecortada = imcrop(app.ImagenTrabajo, cajaPlaca);
    
    % 6. Mostrar el resultado en el panel de recorte (UIAxes_2)
    imshow(placaRecortada, 'Parent', app.UIAxes_9);
    title(app.UIAxes_9, 'Placa Detectada Automáticamente');
    
    % 7. Opcional: Dibujar un rectángulo verde sobre la imagen original (UIAxes)
    % Esto te ayuda a visualizar exactamente dónde hizo la detección el algoritmo
    imshow(app.ImagenTrabajo, 'Parent', app.UIAxes); % Refresca la original
    title(app.UIAxes, 'Imagen Original con Detección');
    hold(app.UIAxes, 'on');
    rectangle(app.UIAxes, 'Position', cajaPlaca, 'EdgeColor', 'g', 'LineWidth', 3);
    hold(app.UIAxes, 'off');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CargaTab = uitab(app.TabGroup);
            app.CargaTab.Title = 'Carga';
            app.UIAxes = uiaxes(app.CargaTab);
            title(app.UIAxes, 'Imagen Original')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.CargaTab);
            title(app.UIAxes_2, 'Recorte')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.CargarImagenButton = uibutton(app.CargaTab, 'push');
            app.CargarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarImagenButtonPushed, true);
            app.CargarImagenButton.Text = 'Cargar Imagen';
            app.RecortarmatrizButton = uibutton(app.CargaTab, 'push');
            app.RecortarmatrizButton.ButtonPushedFcn = createCallbackFcn(app, @RecortarmatrizButtonPushed, true);
            app.RecortarmatrizButton.Text = 'Recortar matriz';
            app.GrisesTab = uitab(app.TabGroup);
            app.GrisesTab.Title = 'Grises';
            app.UIAxes_3 = uiaxes(app.GrisesTab);
            title(app.UIAxes_3, 'Escala de grises')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.ConvertirButton = uibutton(app.GrisesTab, 'push');
            app.ConvertirButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertirButtonPushed, true);
            app.ConvertirButton.Text = 'Convertir';
            app.GaussTab = uitab(app.TabGroup);
            app.GaussTab.Title = 'Gauss';
            app.UIAxes_4 = uiaxes(app.GaussTab);
            title(app.UIAxes_4, 'Filtro Gaussiano')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.ConvertirButton_2 = uibutton(app.GaussTab, 'push');
            app.ConvertirButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConvertirButton_2Pushed, true);
            app.ConvertirButton_2.Text = 'Convertir';
            app.ContrasteTab = uitab(app.TabGroup);
            app.ContrasteTab.Title = 'Contraste';
            app.UIAxes_5 = uiaxes(app.ContrasteTab);
            title(app.UIAxes_5, 'Aumentar contraste')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.ConvertirButton_3 = uibutton(app.ContrasteTab, 'push');
            app.ConvertirButton_3.ButtonPushedFcn = createCallbackFcn(app, @ConvertirButton_3Pushed, true);
            app.ConvertirButton_3.Text = 'Convertir';
            app.ResaltarBordesTab = uitab(app.TabGroup);
            app.ResaltarBordesTab.Title = 'Resaltar Bordes';
            app.UIAxes_6 = uiaxes(app.ResaltarBordesTab);
            title(app.UIAxes_6, 'Imagen Original')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.ConvertirButton_4 = uibutton(app.ResaltarBordesTab, 'push');
            app.ConvertirButton_4.ButtonPushedFcn = createCallbackFcn(app, @ConvertirButton_4Pushed, true);
            app.ConvertirButton_4.Text = 'Convertir';
            app.BinariaTab = uitab(app.TabGroup);
            app.BinariaTab.Title = 'Binaria';
            app.UIAxes_7 = uiaxes(app.BinariaTab);
            title(app.UIAxes_7, 'Conversion binaria')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.ConvertirButton_5 = uibutton(app.BinariaTab, 'push');
            app.ConvertirButton_5.ButtonPushedFcn = createCallbackFcn(app, @ConvertirButton_5Pushed, true);
            app.ConvertirButton_5.Text = 'Convertir';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes_8 = uiaxes(app.Tab);
            title(app.UIAxes_8, 'Recorte')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_8.XTick = [];
            app.UIAxes_8.YTick = [];
            app.ConvertirButton_6 = uibutton(app.Tab, 'push');
            app.ConvertirButton_6.ButtonPushedFcn = createCallbackFcn(app, @ConvertirButton_6Pushed, true);
            app.ConvertirButton_6.Text = 'Convertir';
            app.FinalTab = uitab(app.TabGroup);
            app.FinalTab.Title = 'Final';
            app.UIAxes_9 = uiaxes(app.FinalTab);
            title(app.UIAxes_9, 'Recorte')
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_9.XTick = [];
            app.UIAxes_9.YTick = [];
            app.ConvertirButton_7 = uibutton(app.FinalTab, 'push');
            app.ConvertirButton_7.ButtonPushedFcn = createCallbackFcn(app, @ConvertirButton_7Pushed, true);
            app.ConvertirButton_7.Text = 'Convertir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Prototipo1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end